classdef Simulate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        SimulateButton          matlab.ui.control.Button
        TrajectoryButtonGroup   matlab.ui.container.ButtonGroup
        StepButton              matlab.ui.control.RadioButton
        DiamondButton           matlab.ui.control.RadioButton
        CircleButton            matlab.ui.control.RadioButton
        CustomTrajectoryButton  matlab.ui.control.RadioButton
        StopButton              matlab.ui.control.Button
        CustomTrajectoryPanel   matlab.ui.container.Panel
        UITable                 matlab.ui.control.Table
        AddButton               matlab.ui.control.Button
        XEditFieldLabel         matlab.ui.control.Label
        XEditField              matlab.ui.control.NumericEditField
        YEditFieldLabel         matlab.ui.control.Label
        YEditField              matlab.ui.control.NumericEditField
        ZEditFieldLabel         matlab.ui.control.Label
        ZEditField              matlab.ui.control.NumericEditField
        YawEditFieldLabel       matlab.ui.control.Label
        YawEditField            matlab.ui.control.NumericEditField
        tEditFieldLabel         matlab.ui.control.Label
        tEditField              matlab.ui.control.NumericEditField
        SpeedSliderLabel        matlab.ui.control.Label
        SpeedSlider             matlab.ui.control.Slider
    end


    properties (Access = private)
        Data 
    end

    methods (Access = private)
    
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.CustomTrajectoryPanel,'Visible','off')
            axis(app.UIAxes,'equal')
            app.UIAxes.ZGrid = 'on';
            view(app.UIAxes,3);
            
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            set(app.TrajectoryButtonGroup,'Visible','off')
            if(app.StepButton.Value)
                t=@step;
            end
            if(app.DiamondButton.Value)
                t=@diamond;
            end
            if(app.CircleButton.Value)
                t=@circle;
            end
            if(app.CustomTrajectoryButton.Value)
                t=@trajectory_generator;
            end
            
            try
                QP = runsim(app.UIAxes,t,1,app.Data(:,:),app.SpeedSlider.Value);
            catch
                try
                QP = runsim(app.UIAxes,t,1,0,0);
                catch
                end
            end
            try
                QP{1}.TruncateHist();
                % Plot position for each quad
                h_pos{1} = figure('Name', ['Quad ' num2str(1) ' : position']);
                plot_state(h_pos{1}, QP{1}.state_hist(1:3,:), QP{1}.time_hist, 'pos', 'vic');
                plot_state(h_pos{1}, QP{1}.state_des_hist(1:3,:), QP{1}.time_hist, 'pos', 'des');
                % Plot velocity for each quad
                h_vel{1} = figure('Name', ['Quad ' num2str(1) ' : velocity']);
                plot_state(h_vel{1}, QP{1}.state_hist(4:6,:), QP{1}.time_hist, 'vel', 'vic');
                plot_state(h_vel{1}, QP{1}.state_des_hist(4:6,:), QP{1}.time_hist, 'vel', 'des');
            catch
            end


           try
            set(app.TrajectoryButtonGroup,'Visible','on')
           catch 
                
           end
           app.StopButtonPushed();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            try
            app.UIAxes.cla; 
            app.UIAxes.Title.String='Drone';
            catch
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            delete(app)
            
        end

        % Selection changed function: TrajectoryButtonGroup
        function TrajectoryButtonGroupSelectionChanged(app, event)
            selectedButton = app.TrajectoryButtonGroup.SelectedObject;
            if selectedButton==app.CustomTrajectoryButton
                set(app.CustomTrajectoryPanel,'Visible','on');
            else
                set(app.CustomTrajectoryPanel,'Visible','off');
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            addData = [app.tEditField.Value app.XEditField.Value app.YEditField.Value app.ZEditField.Value app.YawEditField.Value];
            app.Data(end+1,:)=addData;
            set(app.UITable,'Data', app.Data);
            hold(app.UIAxes,'on')
            plot3(app.UIAxes,addData(2),addData(3),addData(4),'r*')
            hold(app.UIAxes,'off')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.TrajectoryButtonGroup = uibuttongroup(app.UIFigure);
            app.TrajectoryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrajectoryButtonGroupSelectionChanged, true);
            app.TrajectoryButtonGroup.Title = 'Trajectory';
            app.StepButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.StepButton.Text = 'Step';
            app.StepButton.Value = true;
            app.DiamondButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.DiamondButton.Text = 'Diamond';
            app.CircleButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.CircleButton.Text = 'Circle';
            app.CustomTrajectoryButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.CustomTrajectoryButton.Text = 'Custom Trajectory';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.CustomTrajectoryPanel = uipanel(app.UIFigure);
            app.CustomTrajectoryPanel.Title = 'Custom Trajectory';
            app.UITable = uitable(app.CustomTrajectoryPanel);
            app.UITable.ColumnName = {'t'; 'X'; 'Y'; 'Z'; 'Yaw'};
            app.UITable.RowName = {};
            app.AddButton = uibutton(app.CustomTrajectoryPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.XEditFieldLabel = uilabel(app.CustomTrajectoryPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.CustomTrajectoryPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.CustomTrajectoryPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.CustomTrajectoryPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.CustomTrajectoryPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.CustomTrajectoryPanel, 'numeric');
            app.YawEditFieldLabel = uilabel(app.CustomTrajectoryPanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.CustomTrajectoryPanel, 'numeric');
            app.tEditFieldLabel = uilabel(app.CustomTrajectoryPanel);
            app.tEditFieldLabel.Text = 't';
            app.tEditField = uieditfield(app.CustomTrajectoryPanel, 'numeric');
            app.SpeedSliderLabel = uilabel(app.CustomTrajectoryPanel);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.CustomTrajectoryPanel);
            app.SpeedSlider.Limits = [0 50];
        end
    end

    methods (Access = public)

        % Construct app
        function app = Simulate

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end